classdef Inverse_Cherenkov_Effect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CMTab                           matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        DDeflectogramTab                matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        CalculatedeflectogramButton     matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        TemporalTab                     matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        RestframeTab                    matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        TransversalmomentumTab          matlab.ui.container.Tab
        UIAxes13                        matlab.ui.control.UIAxes
        RestframecenterofmassTab        matlab.ui.container.Tab
        UIAxes14                        matlab.ui.control.UIAxes
        BinningSliderLabel              matlab.ui.control.Label
        BinningSlider                   matlab.ui.control.Slider
        xlimpsEditFieldLabel            matlab.ui.control.Label
        xlimpsEditField                 matlab.ui.control.NumericEditField
        VectorfieldTab                  matlab.ui.container.Tab
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        AutorefreshfieldsSwitchLabel    matlab.ui.control.Label
        AutorefreshfieldsSwitch         matlab.ui.control.Switch
        ProfilesTab                     matlab.ui.container.Tab
        UIAxes10                        matlab.ui.control.UIAxes
        DelaySliderLabel                matlab.ui.control.Label
        DelaySlider                     matlab.ui.control.Slider
        UIAxes11                        matlab.ui.control.UIAxes
        BinningSlider_2Label            matlab.ui.control.Label
        BinningSlider_2                 matlab.ui.control.Slider
        TrajectoriesTab                 matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        OffsetpsSliderLabel             matlab.ui.control.Label
        OffsetpsSlider                  matlab.ui.control.Slider
        LabtimepsSliderLabel            matlab.ui.control.Label
        LabtimepsSlider                 matlab.ui.control.Slider
        saveButton                      matlab.ui.control.Button
        loadButton                      matlab.ui.control.Button
        ExportfiguresButton             matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        LightTab                        matlab.ui.container.Tab
        PulsePanel_2                    matlab.ui.container.Panel
        PhaseButtonGroup                matlab.ui.container.ButtonGroup
        cosButton                       matlab.ui.control.RadioButton
        sinButton                       matlab.ui.control.RadioButton
        cosButton_2                     matlab.ui.control.RadioButton
        arbitraryButton                 matlab.ui.control.RadioButton
        PhasepiSliderLabel              matlab.ui.control.Label
        PhasepiSlider                   matlab.ui.control.Slider
        amplitudeMVmEditFieldLabel      matlab.ui.control.Label
        amplitudeMVmEditField           matlab.ui.control.NumericEditField
        fielddurationpsEditFieldLabel   matlab.ui.control.Label
        fielddurationpsEditField        matlab.ui.control.NumericEditField
        frequencyTHzEditFieldLabel      matlab.ui.control.Label
        frequencyTHzEditField           matlab.ui.control.NumericEditField
        energynJEditFieldLabel          matlab.ui.control.Label
        energynJEditField               matlab.ui.control.NumericEditField
        ShapeButtonGroup_3              matlab.ui.container.ButtonGroup
        GaussButton                     matlab.ui.control.RadioButton
        cos2Button                      matlab.ui.control.RadioButton
        CWButton                        matlab.ui.control.RadioButton
        BeamPanel_2                     matlab.ui.container.Panel
        centermmEditFieldLabel          matlab.ui.control.Label
        centermmEditField               matlab.ui.control.NumericEditField
        fielddiametermmEditFieldLabel   matlab.ui.control.Label
        fielddiametermmEditField        matlab.ui.control.NumericEditField
        PhasevelocitycEditFieldLabel    matlab.ui.control.Label
        PhasevelocitycEditField         matlab.ui.control.NumericEditField
        L1eumEditFieldLabel             matlab.ui.control.Label
        L1eumEditField                  matlab.ui.control.NumericEditField
        ElectronsTab                    matlab.ui.container.Tab
        BeamPanel                       matlab.ui.container.Panel
        diameterumEditFieldLabel        matlab.ui.control.Label
        diameterumEditField             matlab.ui.control.NumericEditField
        offsetumEditFieldLabel          matlab.ui.control.Label
        offsetumEditField               matlab.ui.control.NumericEditField
        divergenceuradEditFieldLabel    matlab.ui.control.Label
        divergenceuradEditField         matlab.ui.control.NumericEditField
        focaldistancecmEditFieldLabel   matlab.ui.control.Label
        focaldistancecmEditField        matlab.ui.control.NumericEditField
        emittancenmradEditFieldLabel    matlab.ui.control.Label
        emittancenmradEditField         matlab.ui.control.NumericEditField
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        GaussButton_2                   matlab.ui.control.RadioButton
        SuperGaussButton                matlab.ui.control.RadioButton
        EnergyPanel                     matlab.ui.container.Panel
        electronenergykeVEditFieldLabel  matlab.ui.control.Label
        electronenergykeVEditField      matlab.ui.control.NumericEditField
        electronvelocitycEditFieldLabel  matlab.ui.control.Label
        electronvelocitycEditField      matlab.ui.control.NumericEditField
        wavelengthpmEditFieldLabel      matlab.ui.control.Label
        wavelengthpmEditField           matlab.ui.control.NumericEditField
        energyspreadeVEditFieldLabel    matlab.ui.control.Label
        energyspreadeVEditField         matlab.ui.control.NumericEditField
        PulsePanel_3                    matlab.ui.container.Panel
        durationfsEditFieldLabel        matlab.ui.control.Label
        durationfsEditField             matlab.ui.control.NumericEditField
        skewnessEditFieldLabel          matlab.ui.control.Label
        skewnessEditField               matlab.ui.control.NumericEditField
        chirpcpsEditFieldLabel          matlab.ui.control.Label
        chirpcpsEditField               matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        GaussianButton                  matlab.ui.control.RadioButton
        SkewedButton                    matlab.ui.control.RadioButton
        bgdurationfsEditFieldLabel      matlab.ui.control.Label
        bgdurationfsEditField           matlab.ui.control.NumericEditField
        bgsignalEditFieldLabel          matlab.ui.control.Label
        bgsignalEditField               matlab.ui.control.NumericEditField
        bgskewnessEditFieldLabel        matlab.ui.control.Label
        bgskewnessEditField             matlab.ui.control.NumericEditField
        SetupTab                        matlab.ui.container.Tab
        AperturePanel                   matlab.ui.container.Panel
        ApertureoffsetumEditFieldLabel  matlab.ui.control.Label
        ApertureoffsetumEditField       matlab.ui.control.NumericEditField
        ApertureSwitchLabel             matlab.ui.control.Label
        ApertureSwitch                  matlab.ui.control.Switch
        AperturexposmmEditFieldLabel    matlab.ui.control.Label
        AperturexposmmEditField         matlab.ui.control.NumericEditField
        ApertureFWumEditFieldLabel      matlab.ui.control.Label
        ApertureFWumEditField           matlab.ui.control.NumericEditField
        AperturethicknessmmEditFieldLabel  matlab.ui.control.Label
        AperturethicknessmmEditField    matlab.ui.control.NumericEditField
        PrismPanel                      matlab.ui.container.Panel
        incidenceangledegEditFieldLabel  matlab.ui.control.Label
        incidenceangledegEditField      matlab.ui.control.NumericEditField
        refractiveindexEditFieldLabel   matlab.ui.control.Label
        refractiveindexEditField        matlab.ui.control.NumericEditField
        PrismlengthmmEditFieldLabel     matlab.ui.control.Label
        PrismlengthmmEditField          matlab.ui.control.NumericEditField
        GapmmEditFieldLabel             matlab.ui.control.Label
        GapmmEditField                  matlab.ui.control.NumericEditField
        SecondprismSwitchLabel          matlab.ui.control.Label
        SecondprismSwitch               matlab.ui.control.Switch
        phaseslipocEditFieldLabel       matlab.ui.control.Label
        phaseslipocEditField            matlab.ui.control.NumericEditField
        DetectorPanel                   matlab.ui.container.Panel
        pointSpreadpixelsEditFieldLabel  matlab.ui.control.Label
        pointSpreadpixelsEditField      matlab.ui.control.NumericEditField
        pixelsizeumEditFieldLabel       matlab.ui.control.Label
        pixelsizeumEditField            matlab.ui.control.NumericEditField
        distancetoscreenmEditFieldLabel  matlab.ui.control.Label
        distancetoscreenmEditField      matlab.ui.control.NumericEditField
        SimulationTab                   matlab.ui.container.Tab
        TrajectoryPanel                 matlab.ui.container.Panel
        TsimpsEditFieldLabel            matlab.ui.control.Label
        TsimpsEditField                 matlab.ui.control.NumericEditField
        NsimstepsEditFieldLabel         matlab.ui.control.Label
        NsimstepsEditField              matlab.ui.control.NumericEditField
        NtrajectoriesEditFieldLabel     matlab.ui.control.Label
        NtrajectoriesEditField          matlab.ui.control.NumericEditField
        MagneticfieldSwitchLabel        matlab.ui.control.Label
        MagneticfieldSwitch             matlab.ui.control.Switch
        PumpProbePanel                  matlab.ui.container.Panel
        delaystepfsEditFieldLabel       matlab.ui.control.Label
        delaystepfsEditField            matlab.ui.control.NumericEditField
        NstepsEditFieldLabel            matlab.ui.control.Label
        NstepsEditField                 matlab.ui.control.NumericEditField
        AlgorithmButtonGroup            matlab.ui.container.ButtonGroup
        EulerButton                     matlab.ui.control.RadioButton
        MidpointButton                  matlab.ui.control.RadioButton
        ForwardButton                   matlab.ui.control.RadioButton
        RK4Button                       matlab.ui.control.RadioButton
        RelativisticeffectsSwitchLabel  matlab.ui.control.Label
        RelativisticeffectsSwitch       matlab.ui.control.Switch
        PrismReflectionSwitchLabel      matlab.ui.control.Label
        PrismReflectionSwitch           matlab.ui.control.Switch
        PrismcroppingSwitchLabel        matlab.ui.control.Label
        PrismcroppingSwitch             matlab.ui.control.Switch
        ExpTab                          matlab.ui.container.Tab
        offsetexpdelayEditFieldLabel    matlab.ui.control.Label
        offsetexpdelayEditField         matlab.ui.control.NumericEditField
        loadexperimentButton            matlab.ui.control.Button
        maxpsEditFieldLabel             matlab.ui.control.Label
        maxpsEditField                  matlab.ui.control.NumericEditField
        autorefreshSwitchLabel          matlab.ui.control.Label
        autorefreshSwitch               matlab.ui.control.Switch
        maxpsEditField_2Label           matlab.ui.control.Label
        maxpsEditField_2                matlab.ui.control.NumericEditField
        AllwidthsdefinedasFWHMhereattheinputinterfaceLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        version2 = 1;
        autorefresh = 0;
        defl_pixels;
        plt;
        exp;
        x_plot_lim;
        t0_2d;
        d_pixels;
        beam;
        THz;
        el;
        sim;
        setup;
        param;
        vx;
        cmvx;
        v_mod;
        theta_comp;
        theta_inter;
        x;
        y;
        Ey_2D;
        Ex_2D;
        Bz_2D;
        cmt0;
        t0;
        n_traj;
        traj_x;
        traj_y;
        el_pulse_shape;
        el_pulse_skewness;
        prism_reflect;
        el_beam_diameter;
        point_spread;
        algorithm
        theta
        n_prism
        w_beam
        x_beam
        cm
        cm2
        B % magnetic field on or off
        dT_delay % [s]
        T_sim % [s]
        N_sim_steps
        g = 1 % relativistic effects
        electron_energy % [eV]
        N_trajectories
        N_delay_steps
        L_to_screen  %[m]
        pixel_size  % [m]
        L_prism
        E0  % [V/m]
        vg  % [m/s]
        tau_THz  % [seconds]
        tau_electron % [seconds]
        w_THz  % [Hz]
        shape 
        evanesc % [m] 
        phi
        y0 % [m]
        THz_model % [string]
        prism_crop
    end
    
    methods (Access = private)
        
        function update_fields(app)
            app.amplitudeMVmEditField.Value = app.THz.E0*1e-6;
            app.PhasevelocitycEditField.Value = 1/app.setup.n/sin(app.THz.theta); % [c]
            app.electronvelocitycEditField.Value = energy_to_velocity(app.el.ene)/cn.c;
            t_slip = app.THz.w0*cos(app.THz.theta)*(1/app.electronvelocitycEditField.Value -1/app.PhasevelocitycEditField.Value)/cn.c;
            oc_slip = t_slip*app.THz.w_rad/2/pi;
            app.phaseslipocEditField.Value = round(oc_slip,2);
            app.L1eumEditField.Value = 1/k_evanescent(app.setup.n,app.THz.theta,app.THz.w_rad)*1e6; % [um]
            app.divergenceuradEditField.Value = emittance_to_angle(app.el.emittance,app.el.w0)*1e6; % [urad]
            app.wavelengthpmEditField.Value =  energy_to_wavelength(app.el.ene)*1e12; % [pm]
        end      
        function update_calculations(app)
            calculate_cm(app);
            calculate_compression(app);
            calculate_field(app);
        end
        function update_plots(app)
            plot_cm_deflection(app);
            plot_compression(app);
            plot_trajectories(app);
            if string(app.AutorefreshfieldsSwitch.Value)=='On'
                plot_field(app);
            end
            plot_profile(app);
            plot_spectrum(app);
        end
        function update_all(app)
            read_inputs(app);
            update_fields(app);
            if  app.autorefresh
                update_calculations(app);
                update_plots(app);
            end
        end
        function read_inputs(app) 
            %% Inputs: all widths defined as FWHM
            %% Monte Carlo: all Normal widths use rms
            %% Gaussian functions: all widths are HWe-1M
            
            %% Setup
            app.setup.n = app.refractiveindexEditField.Value;
            if string(app.PrismReflectionSwitch.Value)=='On'
               app.setup.reflect = 1;
            else
                app.setup.reflect = 0;
            end
            if string(app.PrismcroppingSwitch.Value)=='On'
               app.setup.crop = 1;
            else
                app.setup.crop = 0;
            end
            app.setup.point_spread = app.pointSpreadpixelsEditField.Value;
            app.setup.L_to_screen = app.distancetoscreenmEditField.Value; % [m]
            app.setup.pixel_size = app.pixelsizeumEditField.Value*1e-6; % [m]
            app.setup.L_prism = app.PrismlengthmmEditField.Value*1e-3; % [m] 
            app.setup.d = app.GapmmEditField.Value*1e-3; % [m]

            if string(app.SecondprismSwitch.Value)=='On'
               app.setup.second_prism = 1;
            else
               app.setup.second_prism = 0;
            end
            app.setup.aperture.x = app.AperturexposmmEditField.Value*1e-3; % [m]
            app.setup.aperture.y1 = app.ApertureoffsetumEditField.Value*1e-6 - app.ApertureFWumEditField.Value/2*1e-6; % [m]
            app.setup.aperture.y2 = app.ApertureoffsetumEditField.Value*1e-6 + app.ApertureFWumEditField.Value/2*1e-6; % [m]
            app.setup.aperture.thickness = app.AperturethicknessmmEditField.Value*1e-3; % [m]
            if string(app.ApertureSwitch.Value)=='On'
               app.setup.aperture.crop = 1;
            else
                app.setup.aperture.crop = 0;
            end
            
            %% THz
            app.THz.ene = app.energynJEditField.Value*1e-9; % [J]
            app.THz.w0 = app.fielddiametermmEditField.Value*10^-3/(2*sqrt(log(2))); % [m] HWe-1M in field
            app.THz.x = app.centermmEditField.Value*10^-3; % [m]
            app.THz.tau = app.fielddurationpsEditField.Value*1e-12/(2*sqrt(log(2))); % [seconds] HWe-1M in field
            app.THz.E0 = energy_to_field(app.THz.ene,app.THz.tau,app.THz.w0); % [V/m] 
            app.THz.w_rad = app.frequencyTHzEditField.Value*2*pi*1e12; % [rad/s]
            app.THz.theta = (app.incidenceangledegEditField.Value)*pi/180; % [rad] angle of incidence on surface is (90-prism angle)
            app.THz.shape = app.ShapeButtonGroup_3.SelectedObject.Text;
            app.THz.v_x = cn.c/app.setup.n/sin(app.THz.theta); % phase velocity projecion onto x axis
            if string(app.PhaseButtonGroup.SelectedObject.Text)=='cos+'
                app.THz.phi = 0;
            elseif string(app.PhaseButtonGroup.SelectedObject.Text)=='cos-'
                app.THz.phi = pi;
            elseif string(app.PhaseButtonGroup.SelectedObject.Text)=='sin'
                app.THz.phi = pi/2;
            else
                app.THz.phi = app.PhasepiSlider.Value*pi;
            end   
            
            %% Eletrons
            app.el.ene = app.electronenergykeVEditField.Value*1e3; % [eV]
            app.el.dene = app.energyspreadeVEditField.Value/(2*sqrt(2*log(2))); % [eV] RMS
            app.el.v = energy_to_velocity(app.el.ene); % [m/s]
            app.el.y0 = app.offsetumEditField.Value*1e-6; % [m]
            app.el.tau = app.durationfsEditField.Value*1e-15/(2*sqrt(2*log(2))); % [seconds] RMS
            app.el.skewness = app.skewnessEditField.Value;
            app.el.shape = app.ButtonGroup.SelectedObject.Text;
            app.el.chirp = app.chirpcpsEditField.Value*cn.c/1000; %[c/fs]
            app.el.w0 = app.diameterumEditField.Value/(2*sqrt(2*log(2)))*1e-6; % [m] RMS
            app.el.beam_shape = app.ButtonGroup_2.SelectedObject.Text;
            app.el.sigma = app.divergenceuradEditField.Value*1e-6; % [rad]
            app.el.f = app.focaldistancecmEditField.Value*1e-2; % [m]
            app.el.emittance = app.emittancenmradEditField.Value*1e-9; % [m rad]
            app.el.bg_tau = app.bgdurationfsEditField.Value*1e-15/(2*sqrt(2*log(2))); % [s] RMS
            app.el.bg_skewness = app.bgskewnessEditField.Value;
            app.el.bg_amp = app.bgsignalEditField.Value;
                        display(size(app.el.bg_amp))
            %% Simulation
            app.sim.t_offset = app.OffsetpsSlider.Value*app.maxpsEditField.Value*1e-12; % [s]
            app.sim.N_tr = app.NtrajectoriesEditField.Value; 
            app.sim.algorithm = app.AlgorithmButtonGroup.SelectedObject.Text;
            app.sim.t = app.TsimpsEditField.Value*1e-12; % [seconds]
            app.sim.N_steps = app.NsimstepsEditField.Value;
            app.sim.dT = app.delaystepfsEditField.Value*1e-15; % [seconds]
            app.sim.N_delay = app.NstepsEditField.Value;
            app.sim.dt = app.sim.t/app.sim.N_steps;
            app.sim.lab_time = app.LabtimepsSlider.Value*app.maxpsEditField_2.Value*1e-12;
            if string(app.RelativisticeffectsSwitch.Value)=='On'
               app.sim.g = 1;
            else
                app.sim.g=1e10;
            end
            if string(app.MagneticfieldSwitch.Value)=='On'
                app.sim.B=1;
            else
                app.sim.B=0;
            end
            
            %% App
            if string(app.autorefreshSwitch.Value)=='On'
               app.autorefresh = 1;
            else
                app.autorefresh = 0;
            end
        end 
        function calculate_cm(app)
            [app.cmt0,app.theta,app.cmvx,~,~,~] = pump_probe_scan(app.THz,app.el,app.sim,app.setup);
            app.defl_pixels = angle_to_pixel(app.theta,app.setup.pixel_size,app.setup.L_to_screen);
        end
        function calculate_compression(app)
            [app.t0,app.traj_x,app.traj_y,app.theta_comp,app.vx,app.v_mod,app.theta_inter] = compression_scan(app.THz,app.el,app.sim,app.setup);
        end
        function calculate_field(app)
            if string(app.AutorefreshfieldsSwitch.Value)=='On'
                [app.x,app.y,app.Ex_2D,app.Ey_2D,app.Bz_2D] = E_field_colorplot(app.THz,app.setup,app.sim.lab_time,0);
            end
        end
        function calculate_deflectogram(app)
            [app.t0_2d,theta_2d,shifts] = pump_probe_deflectogram(app.THz,app.el,app.sim,app.setup);
            [app.d_pixels,app.beam] = make_defelctogram_plot(theta_2d,shifts,app.setup);   
        end
        function plot_cm_deflection(app)
            app.plt.cm.x = app.cmt0*1e12;
            app.plt.cm.y = app.defl_pixels;
            
            app.plt.acc.x = app.cmt0*1e12;
            app.plt.acc.y = velocity_to_energy(app.cmvx) - app.el.ene;
            % Plot figures
            fig_cm_deflection(app.UIAxes,app.plt.cm);
            fig_cm_acceleration(app.UIAxes4,app.plt.acc);
            if app.exp.isempty == 0
                app.plt.exp.x = app.exp.delay - app.offsetexpdelayEditField.Value;
                app.plt.exp.y = -app.exp.deflect;
                fig_experiment(app.UIAxes,app.plt.exp); 
            end
        end
        function plot_compression(app)
            if ~isempty(app.traj_x)
            ind_timestep_comp = round(app.sim.lab_time/app.sim.t*app.sim.N_steps)+1;
            x = app.traj_x(:,ind_timestep_comp);
            vx = app.vx(:,ind_timestep_comp);
            y = app.traj_y(:,ind_timestep_comp);
            theta = app.theta_inter(:,ind_timestep_comp);
            theta(isnan(theta)) = [];
            x(isnan(x))=[];
            y(isnan(y))=[];
            vx(isnan(vx))=[]; 
            x_mean = mean(x);
            dt = (x - x_mean)./vx;
            
            app.plt.comp.x = dt*1e12;
            app.plt.comp.xlim = app.xlimpsEditField.Value;
            app.plt.comp.y = y*1e6;
            app.plt.comp.y_cm = app.plt.comp.y - mean(app.plt.comp.y);
            app.plt.comp.slice = round(x_mean*1e3);
            app.plt.comp.theta = theta*1e6;
            app.plt.comp.bins = round(app.BinningSlider.Value*length(app.plt.comp.x));
            
            % Plot figures
            fig_pulse_front(app.UIAxes5,app.plt.comp);
            fig_pulse_front_cm(app.UIAxes14,app.plt.comp);
            fig_pulse_histogram(app.UIAxes6,app.plt.comp);
            fig_pulse_angles(app.UIAxes13,app.plt.comp);
            end
        end
        function plot_trajectories(app)
            app.plt.trajectories.x = app.traj_x.'*1e3;
            app.plt.trajectories.y = app.traj_y.'*1e6;
            app.plt.prism.x = [0 app.setup.L_prism*1e3 app.setup.L_prism*1e3/2];
            app.plt.prism.y = [0 0 -tan(app.THz.theta)*app.setup.L_prism*1e6/2];
            app.plt.prism.second_prism = app.setup.second_prism;
            app.plt.prism.d = app.setup.d*1e6;
            
            % Plot figures
            fig_trajectoies(app.UIAxes3,app.plt.trajectories);
            fig_prism(app.UIAxes3,app.plt.prism);
        end
        function plot_field(app)
            L_evan_position = app.THz.v_x*app.sim.lab_time*1e3;
            N_space = 2;
            sparse_x = [1:2*N_space:length(app.x)];
            sparse_y = [1:N_space:length(app.y)];
            app.plt.vector.x1 = app.x(sparse_x)*1e3;
            app.plt.vector.x2 = app.y(sparse_y)*1e3;
            app.plt.vector.y1 = app.Ex_2D(sparse_x,sparse_y).';
            app.plt.vector.y2 = app.Ey_2D(sparse_x,sparse_y).';
            
            ind_timestep_comp = round(app.sim.lab_time/app.sim.t*app.sim.N_steps)+1;
            app.plt.beam.x = app.traj_x(:,ind_timestep_comp)*1e3;
            app.plt.beam.y = app.traj_y(:,ind_timestep_comp)*1e3;
            app.plt.beam.xlim = [(L_evan_position - 2e-1) (L_evan_position + 2e-1)];
            app.plt.beam.alpha = 10/app.sim.N_tr;

            app.plt.Ecomp.x = app.x*1e3;
            app.plt.Ecomp.Ex = app.Ex_2D(:,1)*1e-6;
            app.plt.Ecomp.Ey = app.Ey_2D(:,1)*1e-6;
            app.plt.Ecomp.Bz = app.Bz_2D(:,1)*1e-6;
            
            % Plot figures
            fig_field(app.UIAxes9,app.plt.vector);
            fig_field_components(app.UIAxes12,app.plt.Ecomp)
            fig_beam(app.UIAxes9,app.plt.beam);
        end
        function plot_profile(app)
            if sum(app.beam,'all')~=0
                ind_time_slice = round(app.DelaySlider.Value*1e-12/app.sim.dT)+app.sim.N_delay/2;
                app.plt.profile.x = app.d_pixels;
                app.plt.profile.y = app.beam(:,ind_time_slice);
                
                % Plot figures
                fig_profile(app.UIAxes10,app.plt.profile);
            end
        end
        function plot_spectrum(app)
            if ~isempty(app.vx)
            ind_timestep_comp = round(app.sim.lab_time/app.sim.t*app.sim.N_steps)+1;
            spectrum = velocity_to_energy(app.vx(:,ind_timestep_comp));
            app.plt.spec.x = spectrum*1e-3;
            app.plt.spec.bins = round(app.BinningSlider_2.Value*length(spectrum));
            % Plot figures
            fig_spectrum(app.UIAxes11,app.plt.spec);
            end
        end
        function plot_deflectogram(app)
            app.plt.defl.x = app.t0_2d*1e12;
            app.plt.defl.y = app.d_pixels; 
            app.plt.defl.data = app.beam;
            app.x_plot_lim = [app.EditField.Value app.EditField_2.Value];
            
            % Plot figures
            fig_deflectogram(app.UIAxes2,app.plt.defl,app.cm,app.x_plot_lim);
            if app.exp.isempty == 0
                app.plt.exp.x = app.exp.delay - app.offsetexpdelayEditField.Value;
                app.plt.exp.y = -app.exp.deflect; 
                fig_experiment(app.UIAxes2,app.plt.exp);     
            end
        end
        function export_figures(app)
                fig1 = figure; ax1 = gca;
                fig_pulse_front(ax1,app.plt.comp);

                fig10 = figure; ax10 = gca;
                fig_pulse_front_cm(ax10,app.plt.comp);
                
                fig11 = figure; ax11 = gca;
                fig_pulse_angles(ax11,app.plt.comp);
                
                fig9 = figure; ax9 = gca;
                fig_pulse_histogram(ax9,app.plt.comp);
                
                fig2 = figure; ax2 = gca;
                fig_trajectoies(ax2,app.plt.trajectories);
                fig_prism(ax2,app.plt.prism);
                
%                 fig3 = figure; ax3 = gca;
%                 fig_field(ax3,app.plt.vector);
%                 fig_beam(ax3,app.plt.beam);
%                 
%                 fig4 = figure; ax4 = gca;
%                 fig_field_components(ax4,app.plt.Ecomp);
                
                
                if sum(app.beam,'all')~=0
                    fig7 = figure; ax7 = gca;
                    fig_deflectogram(ax7,app.plt.defl,app.cm,app.x_plot_lim);
                    
                    fig5 = figure; ax5 = gca;
                    fig_profile(ax5,app.plt.profile);
                end
               
                fig6 = figure; ax6 = gca;
                fig_spectrum(ax6,app.plt.spec);
                
                if app.exp.isempty == 0
                    fig8 = figure; ax8 = gca;
                    fig_experiment(ax8,app.plt.exp); 
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            app.exp.isempty = 1;
            cm0_struct = load('white_jet_extra.mat');
            app.cm = cm0_struct.white_jet_extra;
            cm2_struct = load('redblue.mat');
            app.cm2 = cm2_struct.redblue;
            app.RK4Button.Value = 1;
            update_all(app);
        end

        % Value changed function: distancetoscreenmEditField
        function distancetoscreenmEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: pixelsizeumEditField
        function pixelsizeumEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: durationfsEditField
        function durationfsEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: NtrajectoriesEditField
        function NtrajectoriesEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: electronenergykeVEditField
        function electronenergykeVEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: PrismlengthmmEditField
        function PrismlengthmmEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: amplitudeMVmEditField
        function amplitudeMVmEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: fielddurationpsEditField
        function fielddurationpsEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: frequencyTHzEditField
        function frequencyTHzEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Callback function
        function RelativisticeffectsButtonGroupSelectionChanged(app, event)
            update_all(app);
        end

        % Value changed function: TsimpsEditField
        function TsimpsEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: NsimstepsEditField
        function NsimstepsEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: delaystepfsEditField
        function delaystepfsEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: NstepsEditField
        function NstepsEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Callback function
        function EvanescencemmEditFieldValueChanged(app, event)
            
        end

        % Value changed function: RelativisticeffectsSwitch
        function RelativisticeffectsSwitchValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: MagneticfieldSwitch
        function MagneticfieldSwitchValueChanged(app, event)
            update_all(app);
        end

        % Selection changed function: PhaseButtonGroup
        function PhaseButtonGroupSelectionChanged(app, event)
            update_all(app);
        end

        % Value changed function: offsetumEditField
        function offsetumEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Button pushed function: CalculatedeflectogramButton
        function CalculatedeflectogramButtonPushed(app, event)
            read_inputs(app);
            update_fields(app);
            calculate_deflectogram(app);
            plot_deflectogram(app);
        end

        % Value changed function: fielddiametermmEditField
        function fielddiametermmEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: centermmEditField
        function centermmEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: incidenceangledegEditField
        function incidenceangledegEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: refractiveindexEditField
        function refractiveindexEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Selection changed function: ShapeButtonGroup_3
        function ShapeButtonGroup_3SelectionChanged(app, event)
            update_all(app);
        end

        % Value changed function: PrismcroppingSwitch
        function PrismcroppingSwitchValueChanged(app, event)
            update_all(app);
        end

        % Callback function
        function TimepsSliderValueChanged(app, event)
            
        end

        % Selection changed function: AlgorithmButtonGroup
        function AlgorithmButtonGroupSelectionChanged(app, event)
            update_all(app);
        end

        % Value changed function: pointSpreadpixelsEditField
        function pointSpreadpixelsEditFieldValueChanged(app, event)
            value = app.pointSpreadpixelsEditField.Value;
            
        end

        % Value changed function: diameterumEditField
        function diameterumEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: PrismReflectionSwitch
        function PrismReflectionSwitchValueChanged(app, event)
            if string(app.PrismReflectionSwitch.Value)=='On'
               app.prism_reflect = 1;
            else
               app.prism_reflect = 0;
            end    
            update_all(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            update_all(app);
        end

        % Value changed function: skewnessEditField
        function skewnessEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: PhasepiSlider
        function PhasepiSliderValueChanged(app, event)
            update_all(app);
        end

        % Callback function
        function InjectiondelaySliderValueChanged(app, event)
            
        end

        % Value changed function: LabtimepsSlider
        function LabtimepsSliderValueChanged(app, event)
            read_inputs(app);
            calculate_field(app);
            update_plots(app);
        end

        % Callback function
        function offsetpsEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function tr_idEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function SpinnerValueChanged(app, event)
            
        end

        % Value changed function: OffsetpsSlider
        function OffsetpsSliderValueChanged(app, event)
            read_inputs(app);
            update_fields(app);
            calculate_compression(app);
            calculate_field(app);
            update_plots(app);
        end

        % Value changed function: chirpcpsEditField
        function chirpcpsEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: energynJEditField
        function energynJEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Callback function
        function saveButtonValueChanged(app, event)
            
        end

        % Callback function
        function loadButtonPushed(app, event)
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            plot_deflectogram(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            plot_deflectogram(app);
        end

        % Callback function
        function MirrorSwitchValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: GapmmEditField
        function GapmmEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            parameters = {app.THz,app.el,app.sim,app.setup};
            cm_deflection = {app.cmt0,app.theta,app.vx};       
            deflectogram = {app.t0_2d,app.d_pixels,app.beam,app.traj_x,app.traj_y,app.vx,app.theta_inter};
            compression = {app.t0,app.traj_x,app.traj_y,app.theta_comp,app.vx,app.v_mod};      
            data = {parameters,deflectogram,compression,cm_deflection};
            [filename, pathname] = uiputfile('*.mat');
            save([pathname,filename],'data','-mat');
        end

        % Button pushed function: loadButton
        function loadButtonPushed2(app, event)
            [filename, pathname] = uigetfile('*.mat');
            p = load([pathname,filename]);
            app.THz = p.data{1}{1};
            app.el = p.data{1}{2};
            app.sim = p.data{1}{3};
            app.setup = p.data{1}{4};
            app.t0_2d = p.data{2}{1};
            app.d_pixels = p.data{2}{2};
            app.beam = p.data{2}{3};
            if ~app.version2
                app.traj_x = p.data{2}{4};
                app.traj_y = p.data{2}{5};
                app.vx = p.data{2}{6};
                app.theta_inter = p.data{2}{7};
            end
            
            %% Setup
            app.refractiveindexEditField.Value = app.setup.n;
            app.GapmmEditField.Value = app.setup.d*1e3; % [mm]
            app.AperturexposmmEditField.Value = app.setup.aperture.x*1e3; % [mm]
            app.ApertureoffsetumEditField.Value = (app.setup.aperture.y2 + app.setup.aperture.y1)/2*1e6;  % [um]
            app.ApertureFWumEditField.Value = (app.setup.aperture.y2 - app.setup.aperture.y1)*1e6; % [um]
            app.AperturethicknessmmEditField.Value = app.setup.aperture.thickness*1e3; % [mm]

            if app.setup.reflect == 1
               app.PrismReflectionSwitch.Value='On';
            else
                app.PrismReflectionSwitch.Value='Off';
            end
            if app.setup.crop == 1
               app.PrismcroppingSwitch.Value='On';
            else
               app.PrismcroppingSwitch.Value='Off';
            end
            if app.setup.second_prism == 1
               app.SecondprismSwitch.Value='On';
            else
               app.SecondprismSwitch.Value='Off';
            end
            
            app.pointSpreadpixelsEditField.Value = app.setup.point_spread;
            app.distancetoscreenmEditField.Value = app.setup.L_to_screen; % [m]
            app.pixelsizeumEditField.Value = app.setup.pixel_size*1e6; % [um]
            app.PrismlengthmmEditField.Value = app.setup.L_prism*1e3; % [mm] 
            
            %% THz
            %app.fielddiametermmEditField.Value =
            %app.THz.w0*1e3*2*sqrt(log(2)); % [mm] FWHM in field redundant,
            %must calculated automatically from energy
            app.centermmEditField.Value = app.THz.x*1e3; % [mm]
            app.fielddurationpsEditField.Value = app.THz.tau*1e12*(2*sqrt(log(2))); % [ps] FWHM in field
            app.energynJEditField.Value = app.THz.ene*1e9; % [nJ]
            app.frequencyTHzEditField.Value = app.THz.w_rad/(2*pi)*1e-12; % [THz]
            app.incidenceangledegEditField.Value = app.THz.theta*180/pi; % grad
            app.ShapeButtonGroup_3.SelectedObject.Text = app.THz.shape;
            
            if app.THz.shape == 'Gauss'
                app.GaussButton.Value = 1;
            elseif app.THz.shape == 'cos2'
                app.cos2Button.Value = 1;
            else
                app.CWButton.Value = 1;
            end
            
            if app.THz.phi == 0
               app.cosButton.Value = 1;
            elseif app.THz.phi == pi
                app.cosButton_2.Value =1;
            elseif app.THz.phi == pi/2
                app.sinButton.Value =1;
            else
                 app.arbitraryButton.Value = 1;
                 app.PhasepiSlider.Value = app.THz.phi/pi;
            end   
            
            %% Electrons        
            app.divergenceuradEditField.Value = app.el.sigma*1e6; % [urad]
            app.focaldistancecmEditField.Value = app.el.f*1e2; % [cm]
            app.emittancenmradEditField.Value = app.el.emittance*1e9; % [nm rad]       
            app.electronenergykeVEditField.Value = app.el.ene*1e-3; % [keV]
            app.energyspreadeVEditField.Value = app.el.dene*(2*sqrt(2*log(2))); % [eV]
            app.offsetumEditField.Value = app.el.y0*1e6; % [um]
            app.durationfsEditField.Value = app.el.tau*1e15*(2*sqrt(2*log(2))); % [fs]   
            app.skewnessEditField.Value = app.el.skewness;
            %app.bgsignalEditField.Value = app.el.bg_amp;
            %app.bgskewnessEditField.Value = app.el.bg_skewness;
            app.bgdurationfsEditField.Value = app.el.bg_tau*1e15*(2*sqrt(2*log(2))); % [fs]
            switch app.el.shape
                case 'Gaussian'
                    app.GaussianButton.Value = 1;
                case 'Skewed'
                    app.SkewedButton.Value = 1;
                case 'Lognormal'
                    app.LognormalButton.Value = 1;
            end
            app.chirpcpsEditField.Value = app.el.chirp/cn.c*1e3; 
            app.diameterumEditField.Value = app.el.w0*(2*sqrt(2*log(2)))*1e6; % [um]
            switch app.el.beam_shape
                case 'Gauss'
                    app.GaussButton_2.Value = 1;
                case 'SuperGauss'
                    app.SuperGaussButton.Value = 1;
            end           

            %% Simulation
            app.OffsetpsSlider.Value = 1; % [ps]
            app.maxpsEditField.Value =  app.sim.t_offset*1e12; % [ps]  
            app.LabtimepsSlider.Value = 1;
            app.maxpsEditField_2.Value = app.sim.lab_time/(1e-12);
            app.NtrajectoriesEditField.Value = app.sim.N_tr; 
            app.TsimpsEditField.Value = app.sim.t*1e12; % [seconds]
            app.NsimstepsEditField.Value = app.sim.N_steps;
            app.delaystepfsEditField.Value = app.sim.dT*1e15; % [seconds]
            app.NstepsEditField.Value = app.sim.N_delay;
            if app.sim.g == 1
               app.RelativisticeffectsSwitch.Value='On';
            else
               app.RelativisticeffectsSwitch.Value='Off';
            end
            if app.sim.B == 1
               app.MagneticfieldSwitch.Value='On';
            elseif app.sim.algorithm == 'Euler'
               app.EulerButton.Value = 1;
            elseif app.sim.algorithm == 'Midpoint'
                app.MidpointButton.Value = 1;
            elseif app.sim.algorithm == 'Forward'
                app.ForwardButton.Value = 1;
            elseif app.sim.algorithm == 'RK4'
                app.RK4Button.Value = 1;
            end         
            update_plots(app);
            plot_deflectogram(app);
        end

        % Button pushed function: loadexperimentButton
        function loadexperimentButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat');
            exp_data = struct2cell(load([pathname,filename]));
            app.exp.isempty = 0;
            app.exp.delay = exp_data{1}(:,1);
            app.exp.deflect = exp_data{1}(:,2); 
            app.plt.exp.x = app.exp.delay - app.offsetexpdelayEditField.Value;
            app.plt.exp.y = -app.exp.deflect;
            fig_experiment(app.UIAxes2,app.plt.exp);
            plot_cm_deflection(app);
        end

        % Callback function
        function offsetexpdelayEditFieldValueChanged(app, event)
            app.plt.exp.x = app.exp.delay - app.offsetexpdelayEditField.Value;
            app.plt.exp.y = app.exp.deflect;
            fig_experiment(app.UIAxes2,app.plt.exp);  
        end

        % Value changed function: DelaySlider
        function DelaySliderValueChanged(app, event)
            plot_profile(app);
        end

        % Button pushed function: ExportfiguresButton
        function ExportfiguresButtonPushed(app, event)
            export_figures(app);
        end

        % Value changed function: offsetexpdelayEditField
        function offsetexpdelayEditFieldValueChanged2(app, event)
            plot_deflectogram(app);
            plot_cm_deflection(app);
        end

        % Value changed function: AperturexposmmEditField
        function AperturexposmmEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: ApertureFWumEditField
        function ApertureFWumEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: ApertureSwitch
        function ApertureSwitchValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: ApertureoffsetumEditField
        function ApertureoffsetumEditFieldValueChanged(app, event)
            update_all(app);            
        end

        % Value changed function: AperturethicknessmmEditField
        function AperturethicknessmmEditFieldValueChanged(app, event)
            update_all(app);             
        end

        % Value changed function: autorefreshSwitch
        function autorefreshSwitchValueChanged(app, event)
            update_all(app); 
        end

        % Value changed function: divergenceuradEditField
        function divergenceuradEditFieldValueChanged(app, event)
            update_all(app);  
        end

        % Value changed function: focaldistancecmEditField
        function focaldistancecmEditFieldValueChanged(app, event)
            update_all(app); 
        end

        % Value changed function: SecondprismSwitch
        function SecondprismSwitchValueChanged(app, event)
            update_all(app); 
        end

        % Value changed function: maxpsEditField_2
        function maxpsEditField_2ValueChanged(app, event)
            read_inputs(app);
            calculate_field(app);
            update_plots(app);
        end

        % Value changed function: emittancenmradEditField
        function emittancenmradEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: energyspreadeVEditField
        function energyspreadeVEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Value changed function: xlimpsEditField
        function xlimpsEditFieldValueChanged(app, event)
                update_plots(app);
        end

        % Value changed function: BinningSlider
        function BinningSliderValueChanged(app, event)
                update_plots(app);
        end

        % Value changed function: AutorefreshfieldsSwitch
        function AutorefreshfieldsSwitchValueChanged(app, event)
            update_plots(app); 
        end

        % Value changed function: BinningSlider_2
        function BinningSlider_2ValueChanged(app, event)
            plot_spectrum(app);          
        end

        % Value changed function: maxpsEditField
        function maxpsEditFieldValueChanged(app, event)
            read_inputs(app);
            update_fields(app);
            calculate_compression(app);
            calculate_field(app);
            update_plots(app);
        end

        % Callback function
        function bgsignalEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Callback function
        function bgdurationfsEditFieldValueChanged(app, event)
            update_all(app);
        end

        % Callback function
        function bgskewnessEditFieldValueChanged(app, event)
            update_all(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CMTab = uitab(app.TabGroup);
            app.CMTab.Title = 'CM';
            app.UIAxes = uiaxes(app.CMTab);
            title(app.UIAxes, 'Center-of-mass deflection')
            xlabel(app.UIAxes, 'THz delay (ps)')
            ylabel(app.UIAxes, 'Deflection (pixels)')
            app.UIAxes4 = uiaxes(app.CMTab);
            title(app.UIAxes4, 'Acceleration')
            xlabel(app.UIAxes4, 'THz delay (ps)')
            ylabel(app.UIAxes4, 'dE (eV)')
            app.DDeflectogramTab = uitab(app.TabGroup);
            app.DDeflectogramTab.Title = '2D Deflectogram';
            app.UIAxes2 = uiaxes(app.DDeflectogramTab);
            title(app.UIAxes2, 'Deflectogram')
            xlabel(app.UIAxes2, 'THz delay (ps)')
            ylabel(app.UIAxes2, 'Deflection (pixels)')
            app.CalculatedeflectogramButton = uibutton(app.DDeflectogramTab, 'push');
            app.CalculatedeflectogramButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatedeflectogramButtonPushed, true);
            app.CalculatedeflectogramButton.Text = 'Calculate deflectogram';
            app.EditField = uieditfield(app.DDeflectogramTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = -1;
            app.EditField_2 = uieditfield(app.DDeflectogramTab, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 5;
            app.TemporalTab = uitab(app.TabGroup);
            app.TemporalTab.Title = 'Temporal';
            app.UIAxes6 = uiaxes(app.TemporalTab);
            title(app.UIAxes6, 'Electron pulse duration')
            xlabel(app.UIAxes6, 'Time (ps)')
            ylabel(app.UIAxes6, 'Counts')
            app.TabGroup2 = uitabgroup(app.TemporalTab);
            app.RestframeTab = uitab(app.TabGroup2);
            app.RestframeTab.Title = 'Rest frame';
            app.UIAxes5 = uiaxes(app.RestframeTab);
            title(app.UIAxes5, 'Rest frame trajectories')
            xlabel(app.UIAxes5, 'Time (ps)')
            ylabel(app.UIAxes5, 'x (um)')
            app.TransversalmomentumTab = uitab(app.TabGroup2);
            app.TransversalmomentumTab.Title = 'Transversal momentum';
            app.UIAxes13 = uiaxes(app.TransversalmomentumTab);
            title(app.UIAxes13, 'Momentum trajectories')
            xlabel(app.UIAxes13, 'Time (ps)')
            ylabel(app.UIAxes13, 'urad')
            app.RestframecenterofmassTab = uitab(app.TabGroup2);
            app.RestframecenterofmassTab.Title = 'Rest frame center-of-mass';
            app.UIAxes14 = uiaxes(app.RestframecenterofmassTab);
            title(app.UIAxes14, 'Rest frame trajectories CM relative')
            xlabel(app.UIAxes14, 'Time (ps)')
            ylabel(app.UIAxes14, 'x - xCM (um)')
            app.BinningSliderLabel = uilabel(app.TemporalTab);
            app.BinningSliderLabel.Text = 'Binning';
            app.BinningSlider = uislider(app.TemporalTab);
            app.BinningSlider.Limits = [0 1];
            app.BinningSlider.ValueChangedFcn = createCallbackFcn(app, @BinningSliderValueChanged, true);
            app.BinningSlider.Value = 0.5;
            app.xlimpsEditFieldLabel = uilabel(app.TemporalTab);
            app.xlimpsEditFieldLabel.Text = 'xlim (ps)';
            app.xlimpsEditField = uieditfield(app.TemporalTab, 'numeric');
            app.xlimpsEditField.ValueChangedFcn = createCallbackFcn(app, @xlimpsEditFieldValueChanged, true);
            app.xlimpsEditField.Value = 0.1;
            app.VectorfieldTab = uitab(app.TabGroup);
            app.VectorfieldTab.Title = 'Vector field';
            app.UIAxes9 = uiaxes(app.VectorfieldTab);
            title(app.UIAxes9, 'Evansecent field E-vector map')
            xlabel(app.UIAxes9, 'x (mm)')
            ylabel(app.UIAxes9, 'y (um)')
            app.UIAxes12 = uiaxes(app.VectorfieldTab);
            title(app.UIAxes12, 'Ex / Ey components')
            xlabel(app.UIAxes12, 'time (ps)')
            ylabel(app.UIAxes12, 'E field (MV/m)')
            app.AutorefreshfieldsSwitchLabel = uilabel(app.VectorfieldTab);
            app.AutorefreshfieldsSwitchLabel.Text = 'Auto refresh fields';
            app.AutorefreshfieldsSwitch = uiswitch(app.VectorfieldTab, 'slider');
            app.AutorefreshfieldsSwitch.ValueChangedFcn = createCallbackFcn(app, @AutorefreshfieldsSwitchValueChanged, true);
            app.ProfilesTab = uitab(app.TabGroup);
            app.ProfilesTab.Title = 'Profiles';
            app.UIAxes10 = uiaxes(app.ProfilesTab);
            title(app.UIAxes10, 'Beam profile on the screen')
            xlabel(app.UIAxes10, 'y (pixels)')
            ylabel(app.UIAxes10, 'Counts')
            app.DelaySliderLabel = uilabel(app.ProfilesTab);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.ProfilesTab);
            app.DelaySlider.Limits = [-5 5];
            app.DelaySlider.ValueChangedFcn = createCallbackFcn(app, @DelaySliderValueChanged, true);
            app.UIAxes11 = uiaxes(app.ProfilesTab);
            title(app.UIAxes11, 'Spectrum')
            xlabel(app.UIAxes11, 'Energy (keV)')
            ylabel(app.UIAxes11, 'Y')
            app.BinningSlider_2Label = uilabel(app.ProfilesTab);
            app.BinningSlider_2Label.Text = 'Binning';
            app.BinningSlider_2 = uislider(app.ProfilesTab);
            app.BinningSlider_2.Limits = [0 1];
            app.BinningSlider_2.ValueChangedFcn = createCallbackFcn(app, @BinningSlider_2ValueChanged, true);
            app.BinningSlider_2.Value = 0.5;
            app.TrajectoriesTab = uitab(app.TabGroup);
            app.TrajectoriesTab.Title = 'Trajectories';
            app.UIAxes3 = uiaxes(app.TrajectoriesTab);
            title(app.UIAxes3, 'Ey component')
            xlabel(app.UIAxes3, 'x (mm)')
            ylabel(app.UIAxes3, 'y (um)')
            app.OffsetpsSliderLabel = uilabel(app.UIFigure);
            app.OffsetpsSliderLabel.Text = 'Offset (ps)';
            app.OffsetpsSlider = uislider(app.UIFigure);
            app.OffsetpsSlider.Limits = [-1 1];
            app.OffsetpsSlider.ValueChangedFcn = createCallbackFcn(app, @OffsetpsSliderValueChanged, true);
            app.LabtimepsSliderLabel = uilabel(app.UIFigure);
            app.LabtimepsSliderLabel.Text = 'Lab time (ps)';
            app.LabtimepsSlider = uislider(app.UIFigure);
            app.LabtimepsSlider.Limits = [0 1];
            app.LabtimepsSlider.ValueChangedFcn = createCallbackFcn(app, @LabtimepsSliderValueChanged, true);
            app.LabtimepsSlider.Value = 1;
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed2, true);
            app.loadButton.Text = 'load';
            app.ExportfiguresButton = uibutton(app.UIFigure, 'push');
            app.ExportfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfiguresButtonPushed, true);
            app.ExportfiguresButton.Text = 'Export figures';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.LightTab = uitab(app.TabGroup3);
            app.LightTab.Title = 'Light';
            app.PulsePanel_2 = uipanel(app.LightTab);
            app.PulsePanel_2.Title = 'Pulse';
            app.PhaseButtonGroup = uibuttongroup(app.PulsePanel_2);
            app.PhaseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhaseButtonGroupSelectionChanged, true);
            app.PhaseButtonGroup.Title = 'Phase';
            app.cosButton = uiradiobutton(app.PhaseButtonGroup);
            app.cosButton.Text = 'cos+';
            app.sinButton = uiradiobutton(app.PhaseButtonGroup);
            app.sinButton.Text = 'sin';
            app.cosButton_2 = uiradiobutton(app.PhaseButtonGroup);
            app.cosButton_2.Text = 'cos-';
            app.arbitraryButton = uiradiobutton(app.PhaseButtonGroup);
            app.arbitraryButton.Text = 'arbitrary';
            app.arbitraryButton.Value = true;
            app.PhasepiSliderLabel = uilabel(app.PhaseButtonGroup);
            app.PhasepiSliderLabel.Text = 'Phase (pi)';
            app.PhasepiSlider = uislider(app.PhaseButtonGroup);
            app.PhasepiSlider.Limits = [0 2];
            app.PhasepiSlider.ValueChangedFcn = createCallbackFcn(app, @PhasepiSliderValueChanged, true);
            app.PhasepiSlider.Value = 1.85;
            app.amplitudeMVmEditFieldLabel = uilabel(app.PulsePanel_2);
            app.amplitudeMVmEditFieldLabel.Text = 'amplitude (MV/m)';
            app.amplitudeMVmEditField = uieditfield(app.PulsePanel_2, 'numeric');
            app.amplitudeMVmEditField.ValueChangedFcn = createCallbackFcn(app, @amplitudeMVmEditFieldValueChanged, true);
            app.amplitudeMVmEditField.Value = 0.5;
            app.fielddurationpsEditFieldLabel = uilabel(app.PulsePanel_2);
            app.fielddurationpsEditFieldLabel.Text = 'field duration (ps)';
            app.fielddurationpsEditField = uieditfield(app.PulsePanel_2, 'numeric');
            app.fielddurationpsEditField.ValueChangedFcn = createCallbackFcn(app, @fielddurationpsEditFieldValueChanged, true);
            app.fielddurationpsEditField.Value = 1.6;
            app.frequencyTHzEditFieldLabel = uilabel(app.PulsePanel_2);
            app.frequencyTHzEditFieldLabel.Text = 'frequency (THz)';
            app.frequencyTHzEditField = uieditfield(app.PulsePanel_2, 'numeric');
            app.frequencyTHzEditField.ValueChangedFcn = createCallbackFcn(app, @frequencyTHzEditFieldValueChanged, true);
            app.frequencyTHzEditField.Value = 0.3;
            app.energynJEditFieldLabel = uilabel(app.PulsePanel_2);
            app.energynJEditFieldLabel.Text = 'energy (nJ)';
            app.energynJEditField = uieditfield(app.PulsePanel_2, 'numeric');
            app.energynJEditField.ValueChangedFcn = createCallbackFcn(app, @energynJEditFieldValueChanged, true);
            app.energynJEditField.Value = 0.68;
            app.ShapeButtonGroup_3 = uibuttongroup(app.PulsePanel_2);
            app.ShapeButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ShapeButtonGroup_3SelectionChanged, true);
            app.ShapeButtonGroup_3.Title = 'Shape';
            app.GaussButton = uiradiobutton(app.ShapeButtonGroup_3);
            app.GaussButton.Text = 'Gauss';
            app.GaussButton.Value = true;
            app.cos2Button = uiradiobutton(app.ShapeButtonGroup_3);
            app.cos2Button.Text = 'cos2';
            app.CWButton = uiradiobutton(app.ShapeButtonGroup_3);
            app.CWButton.Text = 'CW';
            app.BeamPanel_2 = uipanel(app.LightTab);
            app.BeamPanel_2.Title = 'Beam';
            app.centermmEditFieldLabel = uilabel(app.BeamPanel_2);
            app.centermmEditFieldLabel.Text = 'center (mm)';
            app.centermmEditField = uieditfield(app.BeamPanel_2, 'numeric');
            app.centermmEditField.ValueChangedFcn = createCallbackFcn(app, @centermmEditFieldValueChanged, true);
            app.centermmEditField.Value = 5;
            app.fielddiametermmEditFieldLabel = uilabel(app.BeamPanel_2);
            app.fielddiametermmEditFieldLabel.Text = 'field diameter (mm)';
            app.fielddiametermmEditField = uieditfield(app.BeamPanel_2, 'numeric');
            app.fielddiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @fielddiametermmEditFieldValueChanged, true);
            app.fielddiametermmEditField.Value = 3;
            app.PhasevelocitycEditFieldLabel = uilabel(app.LightTab);
            app.PhasevelocitycEditFieldLabel.Text = 'Phase velocity (c)';
            app.PhasevelocitycEditField = uieditfield(app.LightTab, 'numeric');
            app.L1eumEditFieldLabel = uilabel(app.LightTab);
            app.L1eumEditFieldLabel.Text = 'L 1/e (um)';
            app.L1eumEditField = uieditfield(app.LightTab, 'numeric');
            app.ElectronsTab = uitab(app.TabGroup3);
            app.ElectronsTab.Title = 'Electrons';
            app.BeamPanel = uipanel(app.ElectronsTab);
            app.BeamPanel.Title = 'Beam';
            app.diameterumEditFieldLabel = uilabel(app.BeamPanel);
            app.diameterumEditFieldLabel.Text = 'diameter (um)';
            app.diameterumEditField = uieditfield(app.BeamPanel, 'numeric');
            app.diameterumEditField.ValueChangedFcn = createCallbackFcn(app, @diameterumEditFieldValueChanged, true);
            app.diameterumEditField.Value = 15;
            app.offsetumEditFieldLabel = uilabel(app.BeamPanel);
            app.offsetumEditFieldLabel.Text = 'offset (um)';
            app.offsetumEditField = uieditfield(app.BeamPanel, 'numeric');
            app.offsetumEditField.ValueChangedFcn = createCallbackFcn(app, @offsetumEditFieldValueChanged, true);
            app.offsetumEditField.Value = 20;
            app.divergenceuradEditFieldLabel = uilabel(app.BeamPanel);
            app.divergenceuradEditFieldLabel.Text = 'divergence (urad)';
            app.divergenceuradEditField = uieditfield(app.BeamPanel, 'numeric');
            app.divergenceuradEditField.ValueChangedFcn = createCallbackFcn(app, @divergenceuradEditFieldValueChanged, true);
            app.focaldistancecmEditFieldLabel = uilabel(app.BeamPanel);
            app.focaldistancecmEditFieldLabel.Text = 'focal distance (cm)';
            app.focaldistancecmEditField = uieditfield(app.BeamPanel, 'numeric');
            app.focaldistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @focaldistancecmEditFieldValueChanged, true);
            app.focaldistancecmEditField.Value = 1000;
            app.emittancenmradEditFieldLabel = uilabel(app.BeamPanel);
            app.emittancenmradEditFieldLabel.Text = 'emittance (nm rad)';
            app.emittancenmradEditField = uieditfield(app.BeamPanel, 'numeric');
            app.emittancenmradEditField.ValueChangedFcn = createCallbackFcn(app, @emittancenmradEditFieldValueChanged, true);
            app.emittancenmradEditField.Value = 10;
            app.ButtonGroup_2 = uibuttongroup(app.BeamPanel);
            app.GaussButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.GaussButton_2.Text = 'Gauss';
            app.GaussButton_2.Value = true;
            app.SuperGaussButton = uiradiobutton(app.ButtonGroup_2);
            app.SuperGaussButton.Text = 'SuperGauss';
            app.EnergyPanel = uipanel(app.ElectronsTab);
            app.EnergyPanel.Title = 'Energy';
            app.electronenergykeVEditFieldLabel = uilabel(app.EnergyPanel);
            app.electronenergykeVEditFieldLabel.Text = 'electron energy (keV)';
            app.electronenergykeVEditField = uieditfield(app.EnergyPanel, 'numeric');
            app.electronenergykeVEditField.ValueChangedFcn = createCallbackFcn(app, @electronenergykeVEditFieldValueChanged, true);
            app.electronenergykeVEditField.Value = 70;
            app.electronvelocitycEditFieldLabel = uilabel(app.EnergyPanel);
            app.electronvelocitycEditFieldLabel.Text = 'electron velocity (c)';
            app.electronvelocitycEditField = uieditfield(app.EnergyPanel, 'numeric');
            app.wavelengthpmEditFieldLabel = uilabel(app.EnergyPanel);
            app.wavelengthpmEditFieldLabel.Text = 'wavelength (pm)';
            app.wavelengthpmEditField = uieditfield(app.EnergyPanel, 'numeric');
            app.energyspreadeVEditFieldLabel = uilabel(app.EnergyPanel);
            app.energyspreadeVEditFieldLabel.Text = 'energy spread (eV)';
            app.energyspreadeVEditField = uieditfield(app.EnergyPanel, 'numeric');
            app.energyspreadeVEditField.ValueChangedFcn = createCallbackFcn(app, @energyspreadeVEditFieldValueChanged, true);
            app.energyspreadeVEditField.Value = 1.3;
            app.PulsePanel_3 = uipanel(app.ElectronsTab);
            app.PulsePanel_3.Title = 'Pulse';
            app.durationfsEditFieldLabel = uilabel(app.PulsePanel_3);
            app.durationfsEditFieldLabel.Text = 'duration (fs)';
            app.durationfsEditField = uieditfield(app.PulsePanel_3, 'numeric');
            app.durationfsEditField.ValueChangedFcn = createCallbackFcn(app, @durationfsEditFieldValueChanged, true);
            app.durationfsEditField.Value = 30;
            app.skewnessEditFieldLabel = uilabel(app.PulsePanel_3);
            app.skewnessEditFieldLabel.Text = 'skewness';
            app.skewnessEditField = uieditfield(app.PulsePanel_3, 'numeric');
            app.skewnessEditField.ValueChangedFcn = createCallbackFcn(app, @skewnessEditFieldValueChanged, true);
            app.chirpcpsEditFieldLabel = uilabel(app.PulsePanel_3);
            app.chirpcpsEditFieldLabel.Text = 'chirp (c/ps)';
            app.chirpcpsEditField = uieditfield(app.PulsePanel_3, 'numeric');
            app.chirpcpsEditField.ValueChangedFcn = createCallbackFcn(app, @chirpcpsEditFieldValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.PulsePanel_3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GaussianButton = uiradiobutton(app.ButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.GaussianButton.Value = true;
            app.SkewedButton = uiradiobutton(app.ButtonGroup);
            app.SkewedButton.Text = 'Skewed';
            app.bgdurationfsEditFieldLabel = uilabel(app.PulsePanel_3);
            app.bgdurationfsEditFieldLabel.Text = 'bg duration (fs)';
            app.bgdurationfsEditField = uieditfield(app.PulsePanel_3, 'numeric');
            app.bgdurationfsEditField.Value = 280;
            app.bgsignalEditFieldLabel = uilabel(app.PulsePanel_3);
            app.bgsignalEditFieldLabel.Text = 'bg / signal';
            app.bgsignalEditField = uieditfield(app.PulsePanel_3, 'numeric');
            app.bgsignalEditField.Value = 0.4;
            app.bgskewnessEditFieldLabel = uilabel(app.PulsePanel_3);
            app.bgskewnessEditFieldLabel.Text = 'bg skewness';
            app.bgskewnessEditField = uieditfield(app.PulsePanel_3, 'numeric');
            app.bgskewnessEditField.Value = -5;
            app.SetupTab = uitab(app.TabGroup3);
            app.SetupTab.Title = 'Setup';
            app.AperturePanel = uipanel(app.SetupTab);
            app.AperturePanel.Title = 'Aperture';
            app.ApertureoffsetumEditFieldLabel = uilabel(app.AperturePanel);
            app.ApertureoffsetumEditFieldLabel.Text = 'Aperture offset (um)';
            app.ApertureoffsetumEditField = uieditfield(app.AperturePanel, 'numeric');
            app.ApertureoffsetumEditField.ValueChangedFcn = createCallbackFcn(app, @ApertureoffsetumEditFieldValueChanged, true);
            app.ApertureoffsetumEditField.Value = 10;
            app.ApertureSwitchLabel = uilabel(app.AperturePanel);
            app.ApertureSwitchLabel.Text = 'Aperture';
            app.ApertureSwitch = uiswitch(app.AperturePanel, 'slider');
            app.ApertureSwitch.ValueChangedFcn = createCallbackFcn(app, @ApertureSwitchValueChanged, true);
            app.AperturexposmmEditFieldLabel = uilabel(app.AperturePanel);
            app.AperturexposmmEditFieldLabel.Text = 'Aperture x pos. (mm)';
            app.AperturexposmmEditField = uieditfield(app.AperturePanel, 'numeric');
            app.AperturexposmmEditField.ValueChangedFcn = createCallbackFcn(app, @AperturexposmmEditFieldValueChanged, true);
            app.AperturexposmmEditField.Value = 10;
            app.ApertureFWumEditFieldLabel = uilabel(app.AperturePanel);
            app.ApertureFWumEditFieldLabel.Text = 'Aperture FW (um)';
            app.ApertureFWumEditField = uieditfield(app.AperturePanel, 'numeric');
            app.ApertureFWumEditField.ValueChangedFcn = createCallbackFcn(app, @ApertureFWumEditFieldValueChanged, true);
            app.ApertureFWumEditField.Value = 10;
            app.AperturethicknessmmEditFieldLabel = uilabel(app.AperturePanel);
            app.AperturethicknessmmEditFieldLabel.Text = 'Aperture thickness (mm)';
            app.AperturethicknessmmEditField = uieditfield(app.AperturePanel, 'numeric');
            app.AperturethicknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @AperturethicknessmmEditFieldValueChanged, true);
            app.AperturethicknessmmEditField.Value = 1;
            app.PrismPanel = uipanel(app.SetupTab);
            app.PrismPanel.Title = 'Prism';
            app.incidenceangledegEditFieldLabel = uilabel(app.PrismPanel);
            app.incidenceangledegEditFieldLabel.Text = 'incidence angle (deg)';
            app.incidenceangledegEditField = uieditfield(app.PrismPanel, 'numeric');
            app.incidenceangledegEditField.ValueChangedFcn = createCallbackFcn(app, @incidenceangledegEditFieldValueChanged, true);
            app.incidenceangledegEditField.Value = 38;
            app.refractiveindexEditFieldLabel = uilabel(app.PrismPanel);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.PrismPanel, 'numeric');
            app.refractiveindexEditField.ValueChangedFcn = createCallbackFcn(app, @refractiveindexEditFieldValueChanged, true);
            app.refractiveindexEditField.Value = 3.418;
            app.PrismlengthmmEditFieldLabel = uilabel(app.PrismPanel);
            app.PrismlengthmmEditFieldLabel.Text = 'Prism length (mm)';
            app.PrismlengthmmEditField = uieditfield(app.PrismPanel, 'numeric');
            app.PrismlengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @PrismlengthmmEditFieldValueChanged, true);
            app.PrismlengthmmEditField.Value = 10;
            app.GapmmEditFieldLabel = uilabel(app.PrismPanel);
            app.GapmmEditFieldLabel.Text = 'Gap (mm)';
            app.GapmmEditField = uieditfield(app.PrismPanel, 'numeric');
            app.GapmmEditField.ValueChangedFcn = createCallbackFcn(app, @GapmmEditFieldValueChanged, true);
            app.SecondprismSwitchLabel = uilabel(app.PrismPanel);
            app.SecondprismSwitchLabel.Text = 'Second prism';
            app.SecondprismSwitch = uiswitch(app.PrismPanel, 'slider');
            app.SecondprismSwitch.ValueChangedFcn = createCallbackFcn(app, @SecondprismSwitchValueChanged, true);
            app.phaseslipocEditFieldLabel = uilabel(app.PrismPanel);
            app.phaseslipocEditFieldLabel.Text = 'phase slip (o.c.)';
            app.phaseslipocEditField = uieditfield(app.PrismPanel, 'numeric');
            app.DetectorPanel = uipanel(app.SetupTab);
            app.DetectorPanel.Title = 'Detector';
            app.pointSpreadpixelsEditFieldLabel = uilabel(app.DetectorPanel);
            app.pointSpreadpixelsEditFieldLabel.Text = 'point Spread (pixels)';
            app.pointSpreadpixelsEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.pointSpreadpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @pointSpreadpixelsEditFieldValueChanged, true);
            app.pointSpreadpixelsEditField.Value = 2.5;
            app.pixelsizeumEditFieldLabel = uilabel(app.DetectorPanel);
            app.pixelsizeumEditFieldLabel.Text = 'pixel size (um)';
            app.pixelsizeumEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.pixelsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @pixelsizeumEditFieldValueChanged, true);
            app.pixelsizeumEditField.Value = 15.6;
            app.distancetoscreenmEditFieldLabel = uilabel(app.DetectorPanel);
            app.distancetoscreenmEditFieldLabel.Text = 'distance to screen (m)';
            app.distancetoscreenmEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.distancetoscreenmEditField.ValueChangedFcn = createCallbackFcn(app, @distancetoscreenmEditFieldValueChanged, true);
            app.distancetoscreenmEditField.Value = 1.35;
            app.SimulationTab = uitab(app.TabGroup3);
            app.SimulationTab.Title = 'Simulation';
            app.TrajectoryPanel = uipanel(app.SimulationTab);
            app.TrajectoryPanel.Title = 'Trajectory';
            app.TsimpsEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.TsimpsEditFieldLabel.Text = 'T sim (ps)';
            app.TsimpsEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.TsimpsEditField.ValueChangedFcn = createCallbackFcn(app, @TsimpsEditFieldValueChanged, true);
            app.TsimpsEditField.Value = 200;
            app.NsimstepsEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.NsimstepsEditFieldLabel.Text = 'N sim steps';
            app.NsimstepsEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.NsimstepsEditField.ValueChangedFcn = createCallbackFcn(app, @NsimstepsEditFieldValueChanged, true);
            app.NsimstepsEditField.Value = 1000;
            app.NtrajectoriesEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.NtrajectoriesEditFieldLabel.Text = 'N trajectories';
            app.NtrajectoriesEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.NtrajectoriesEditField.ValueChangedFcn = createCallbackFcn(app, @NtrajectoriesEditFieldValueChanged, true);
            app.NtrajectoriesEditField.Value = 100;
            app.MagneticfieldSwitchLabel = uilabel(app.TrajectoryPanel);
            app.MagneticfieldSwitchLabel.Text = 'Magnetic field';
            app.MagneticfieldSwitch = uiswitch(app.TrajectoryPanel, 'slider');
            app.MagneticfieldSwitch.ValueChangedFcn = createCallbackFcn(app, @MagneticfieldSwitchValueChanged, true);
            app.MagneticfieldSwitch.Value = 'On';
            app.PumpProbePanel = uipanel(app.SimulationTab);
            app.PumpProbePanel.Title = 'Pump-Probe';
            app.delaystepfsEditFieldLabel = uilabel(app.PumpProbePanel);
            app.delaystepfsEditFieldLabel.Text = 'delay step (fs)';
            app.delaystepfsEditField = uieditfield(app.PumpProbePanel, 'numeric');
            app.delaystepfsEditField.ValueChangedFcn = createCallbackFcn(app, @delaystepfsEditFieldValueChanged, true);
            app.delaystepfsEditField.Value = 25;
            app.NstepsEditFieldLabel = uilabel(app.PumpProbePanel);
            app.NstepsEditFieldLabel.Text = 'N steps';
            app.NstepsEditField = uieditfield(app.PumpProbePanel, 'numeric');
            app.NstepsEditField.ValueChangedFcn = createCallbackFcn(app, @NstepsEditFieldValueChanged, true);
            app.NstepsEditField.Value = 300;
            app.AlgorithmButtonGroup = uibuttongroup(app.SimulationTab);
            app.AlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmButtonGroupSelectionChanged, true);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.EulerButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.MidpointButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.MidpointButton.Text = 'Midpoint';
            app.ForwardButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.ForwardButton.Text = 'Forward';
            app.RK4Button = uiradiobutton(app.AlgorithmButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.RelativisticeffectsSwitchLabel = uilabel(app.SimulationTab);
            app.RelativisticeffectsSwitchLabel.Text = 'Relativistic effects';
            app.RelativisticeffectsSwitch = uiswitch(app.SimulationTab, 'slider');
            app.RelativisticeffectsSwitch.ValueChangedFcn = createCallbackFcn(app, @RelativisticeffectsSwitchValueChanged, true);
            app.RelativisticeffectsSwitch.Value = 'On';
            app.PrismReflectionSwitchLabel = uilabel(app.SimulationTab);
            app.PrismReflectionSwitchLabel.Text = 'Prism Reflection';
            app.PrismReflectionSwitch = uiswitch(app.SimulationTab, 'slider');
            app.PrismReflectionSwitch.ValueChangedFcn = createCallbackFcn(app, @PrismReflectionSwitchValueChanged, true);
            app.PrismcroppingSwitchLabel = uilabel(app.SimulationTab);
            app.PrismcroppingSwitchLabel.Text = 'Prism cropping';
            app.PrismcroppingSwitch = uiswitch(app.SimulationTab, 'slider');
            app.PrismcroppingSwitch.ValueChangedFcn = createCallbackFcn(app, @PrismcroppingSwitchValueChanged, true);
            app.PrismcroppingSwitch.Value = 'On';
            app.ExpTab = uitab(app.TabGroup3);
            app.ExpTab.Title = 'Exp.';
            app.offsetexpdelayEditFieldLabel = uilabel(app.ExpTab);
            app.offsetexpdelayEditFieldLabel.Text = 'offset exp delay';
            app.offsetexpdelayEditField = uieditfield(app.ExpTab, 'numeric');
            app.offsetexpdelayEditField.ValueChangedFcn = createCallbackFcn(app, @offsetexpdelayEditFieldValueChanged2, true);
            app.loadexperimentButton = uibutton(app.ExpTab, 'push');
            app.loadexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @loadexperimentButtonPushed, true);
            app.loadexperimentButton.Text = 'load experiment';
            app.maxpsEditFieldLabel = uilabel(app.UIFigure);
            app.maxpsEditFieldLabel.Text = 'max (ps)';
            app.maxpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxpsEditField.ValueChangedFcn = createCallbackFcn(app, @maxpsEditFieldValueChanged, true);
            app.maxpsEditField.Value = 1;
            app.autorefreshSwitchLabel = uilabel(app.UIFigure);
            app.autorefreshSwitchLabel.Text = 'auto refresh';
            app.autorefreshSwitch = uiswitch(app.UIFigure, 'slider');
            app.autorefreshSwitch.ValueChangedFcn = createCallbackFcn(app, @autorefreshSwitchValueChanged, true);
            app.maxpsEditField_2Label = uilabel(app.UIFigure);
            app.maxpsEditField_2Label.Text = 'max (ps)';
            app.maxpsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.maxpsEditField_2.ValueChangedFcn = createCallbackFcn(app, @maxpsEditField_2ValueChanged, true);
            app.maxpsEditField_2.Value = 1;
            app.AllwidthsdefinedasFWHMhereattheinputinterfaceLabel = uilabel(app.UIFigure);
            app.AllwidthsdefinedasFWHMhereattheinputinterfaceLabel.Text = 'All widths defined as FWHM (here at the input interface)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverse_Cherenkov_Effect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end